classdef analysisROC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TPRMinEditFieldLabel  matlab.ui.control.Label
        TPRMin                matlab.ui.control.NumericEditField
        FPRMaxEditFieldLabel  matlab.ui.control.Label
        FPRMax                matlab.ui.control.NumericEditField
        FileEditFieldLabel    matlab.ui.control.Label
        File                  matlab.ui.control.EditField
        NDropDownLabel        matlab.ui.control.Label
        NSel                  matlab.ui.control.DropDown
        MDropDownLabel        matlab.ui.control.Label
        MSel                  matlab.ui.control.DropDown
        DDropDownLabel        matlab.ui.control.Label
        DSel                  matlab.ui.control.DropDown
        Ax                    matlab.ui.control.UIAxes
        SearchResults         matlab.ui.control.Table
        LoadButton            matlab.ui.control.Button
        SearchButton          matlab.ui.control.Button
        PlotselectedButton    matlab.ui.control.Button
        ResetLimitsButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Res % Resultados
        thr % Threshold da análise
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            temp = load(app.File.Value);
            app.Res = temp.Res;
            app.thr = temp.thr;
            N_cell = cellstr(num2str(temp.N'));
            M_cell = cellstr(num2str(temp.M'));
            D_cell = cellstr(num2str(temp.D'));
            app.NSel.Items = N_cell;
            app.NSel.ItemsData = temp.N;
            app.MSel.Items = M_cell;
            app.MSel.ItemsData = temp.M;
            app.DSel.Items = D_cell;
            app.DSel.ItemsData = temp.D;
            app.SearchButton.Enable = 'on';
            app.PlotselectedButton.Enable = 'on';
        end

        % Button pushed function: PlotselectedButton
        function PlotselectedButtonPushed(app, event)
            nI = find(str2double(app.NSel.Items)==app.NSel.Value);
            mI = find(str2double(app.MSel.Items)==app.MSel.Value);
            dI = find(str2double(app.DSel.Items)==app.DSel.Value);
            
            p = plot(app.Ax, app.Res(nI,mI,dI).FPR,app.Res(nI,mI,dI).TPR);
            row = dataTipTextRow('Thr',app.thr);
            p.DataTipTemplate.DataTipRows(end+1) = row;
            p.DataTipTemplate.DataTipRows(1).Label = 'FPR';
            p.DataTipTemplate.DataTipRows(2).Label = 'TPR'; 
            xlim(app.Ax,[0 1]); ylim(app.Ax,[0 1]);
            app.ResetLimitsButton.Enable = 'on';
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.SearchResults.Enable = 'off';
            sz = size(app.Res);
            data = nan(sz);
            for k1 = 1:sz(1)
                for k2 = 1:sz(2)
                    for k3 = 1:sz(3)
                        temp = max(app.Res(k1,k2,k3).TPR((app.Res(k1,k2,k3).FPR<=app.FPRMax.Value) & (app.Res(k1,k2,k3).TPR>=app.TPRMin.Value)));
                        if isempty(temp)
                            data(k1,k2,k3) = NaN;
                        else
                            data(k1,k2,k3) = 1;
                        end
                    end
                end
            end
            
            in = find(~isnan(data));
            [n,m,d] = ind2sub(sz,in);
            
            TPRMax = nan(length(in),1);
            fprAtTPRMax = nan(length(in),1);
            thrAtTPRMax = nan(length(in),1);
            FPRMin = nan(length(in),1);
            tprAtFPRMin = nan(length(in),1);
            thrAtFPRMin = nan(length(in),1);
                
            for k0 = 1:length(in)
                rTemp = app.Res(n(k0),m(k0),d(k0));
                TPRMax(k0) = max(rTemp.TPR(rTemp.FPR<=app.FPRMax.Value));
                fprAtTPRMax(k0) = min(rTemp.FPR(rTemp.TPR == TPRMax(k0)));
                thrAtTPRMax(k0) = min(app.thr((rTemp.FPR == fprAtTPRMax(k0)) & (rTemp.TPR == TPRMax(k0))));
                
                FPRMin(k0) = min(rTemp.FPR(rTemp.TPR>=app.TPRMin.Value));
                tprAtFPRMin(k0) = max(rTemp.TPR(rTemp.FPR == FPRMin(k0)));
                thrAtFPRMin(k0) = min(app.thr((rTemp.TPR == tprAtFPRMin(k0)) & (rTemp.FPR == FPRMin(k0))));
                
                n(k0) = app.NSel.ItemsData(n(k0));
                m(k0) = app.MSel.ItemsData(m(k0));
                d(k0) = app.DSel.ItemsData(d(k0));
            end
            vars = {'N','M','D','MaxTPR','FPRAtMax','thrAtMax','MinFPR','TPRAtMin','thrAtmin'};
            t = table(n,m,d,TPRMax,fprAtTPRMax,thrAtTPRMax,FPRMin,tprAtFPRMin,thrAtFPRMin, 'VariableNames', vars);
            t = sortrows(t,{'N','D','MinFPR'},{'ascend','ascend','ascend'});
            app.SearchResults.Data = t;
            app.SearchResults.Enable = 'on';
        end

        % Button pushed function: ResetLimitsButton
        function ResetLimitsButtonPushed(app, event)
            xlim(app.Ax,[0 app.FPRMax.Value]);
            ylim(app.Ax,[app.TPRMin.Value 1]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TPRMinEditFieldLabel = uilabel(app.UIFigure);
            app.TPRMinEditFieldLabel.Text = 'TPR Min';
            app.TPRMin = uieditfield(app.UIFigure, 'numeric');
            app.TPRMin.Limits = [0 1];
            app.FPRMaxEditFieldLabel = uilabel(app.UIFigure);
            app.FPRMaxEditFieldLabel.Text = 'FPR Max';
            app.FPRMax = uieditfield(app.UIFigure, 'numeric');
            app.FPRMax.Limits = [0 1];
            app.FPRMax.Value = 1;
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.File = uieditfield(app.UIFigure, 'text');
            app.File.Value = 'D:\Documentos\Amaciamento\Ferramentas\ProjetoMatlab\method_logisticRegression\test_2020_10_01\ResultsCat.mat';
            app.NDropDownLabel = uilabel(app.UIFigure);
            app.NDropDownLabel.Text = 'N';
            app.NSel = uidropdown(app.UIFigure);
            app.NSel.Items = {};
            app.NSel.Value = {};
            app.MDropDownLabel = uilabel(app.UIFigure);
            app.MDropDownLabel.Text = 'M';
            app.MSel = uidropdown(app.UIFigure);
            app.MSel.Items = {};
            app.MSel.Value = {};
            app.DDropDownLabel = uilabel(app.UIFigure);
            app.DDropDownLabel.Text = 'D';
            app.DSel = uidropdown(app.UIFigure);
            app.DSel.Items = {};
            app.DSel.Value = {};
            app.Ax = uiaxes(app.UIFigure);
            title(app.Ax, 'ROC')
            xlabel(app.Ax, 'False Positive Rate FP/(FP+TN)')
            ylabel(app.Ax, 'True Positive Rate TP/(TP+FN)')
            app.SearchResults = uitable(app.UIFigure);
            app.SearchResults.ColumnName = {'N'; 'M'; 'D'; 'Max TPR'; 'FPR @ Max'; 'thr @ Max'; 'Min FPR'; 'TPR @ Min'; 'thr @ min'};
            app.SearchResults.RowName = {};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Enable = 'off';
            app.SearchButton.Text = 'Search';
            app.PlotselectedButton = uibutton(app.UIFigure, 'push');
            app.PlotselectedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotselectedButtonPushed, true);
            app.PlotselectedButton.Enable = 'off';
            app.PlotselectedButton.Text = 'Plot selected';
            app.ResetLimitsButton = uibutton(app.UIFigure, 'push');
            app.ResetLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetLimitsButtonPushed, true);
            app.ResetLimitsButton.Enable = 'off';
            app.ResetLimitsButton.Text = 'Reset Limits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysisROC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end